classdef Fibonacci_calculator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        SecondTerm                     matlab.ui.control.NumericEditField
        SecondTermEditFieldLabel       matlab.ui.control.Label
        FirstTerm                      matlab.ui.control.NumericEditField
        FirstTermEditFieldLabel        matlab.ui.control.Label
        Result                         matlab.ui.control.TextArea
        ResultTextAreaLabel            matlab.ui.control.Label
        NumberofSquence                matlab.ui.control.NumericEditField
        NumberofSquenceEditFieldLabel  matlab.ui.control.Label
        FibonacciCalculator            matlab.ui.control.Label
        Calculate                      matlab.ui.control.Button
        Graph                          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Calculate
        function CalculatePushed(app, event)
            % Ambil input dari Numeric Edit Field
            n = round(app.NumberofSquence.Value);

            % Ambil nilai suku pertama & kedua
            a = app.FirstTerm.Value;
            b = app.SecondTerm.Value;

            % Validasi input
            if isempty(n) || n < 1
                app.Result.Value = "Please enter n >= 1";
                cla(app.Graph);
                return;
            end
            % Hitung deret Fibonacci dengan suku awal custom
            fib = zeros(1, n);
            fib(1) = a;
            if n > 1
                fib(2) = b;
                for k = 3:n
                    fib(k) = fib(k-1) + fib(k-2);
                end
            end

            % Tampilkan hasil di Text Area
            app.Result.Value = strjoin(string(fib), ' ');

            % Plot grafik
            stem(app.Graph, 1:n, fib, 'filled');
            title(app.Graph, sprintf('Fibonacci-like Sequence (a=%g, b=%g)', a, b));
            xlabel(app.Graph, 'Index');
            ylabel(app.Graph, 'Value');
            grid(app.Graph,'on');
        end

        % Callback function
        function ModeValueChanged(app, event)
            value = app.Mode.Value;
            if strcmp(value, 'Default (0,1)')
                % Nonaktifkan input custom
                app.FirstTerm.Enable = 'off';
                app.SecondTerm.Enable = 'off';
            else
                % Aktifkan input custom
                app.FirstTerm.Enable = 'on';
                app.SecondTerm.Enable = 'on';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Graph = uiaxes(app.UIFigure);
            title(app.Graph, 'Fibonacci Squence Graph')
            xlabel(app.Graph, 'Squence Number')
            ylabel(app.Graph, 'Fibonacci Number')
            zlabel(app.Graph, ' ')
            app.Graph.XTickLabel = {'0'; '0.1'; '0.2'; '0.3'; '0.4'; '0.5'; '0.6'; '0.7'; '0.8'; '0.9'; '1'};
            app.Graph.YTickLabel = {'0'; '0.2'; '0.4'; '0.6'; '0.8'; '1'};
            app.Calculate = uibutton(app.UIFigure, 'push');
            app.Calculate.ButtonPushedFcn = createCallbackFcn(app, @CalculatePushed, true);
            app.Calculate.Text = 'Calculate';
            app.FibonacciCalculator = uilabel(app.UIFigure);
            app.FibonacciCalculator.Text = 'Fibonacci Calculator';
            app.NumberofSquenceEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofSquenceEditFieldLabel.Text = 'Number of Squence';
            app.NumberofSquence = uieditfield(app.UIFigure, 'numeric');
            app.ResultTextAreaLabel = uilabel(app.UIFigure);
            app.ResultTextAreaLabel.Text = 'Result';
            app.Result = uitextarea(app.UIFigure);
            app.FirstTermEditFieldLabel = uilabel(app.UIFigure);
            app.FirstTermEditFieldLabel.Text = 'First Term';
            app.FirstTerm = uieditfield(app.UIFigure, 'numeric');
            app.SecondTermEditFieldLabel = uilabel(app.UIFigure);
            app.SecondTermEditFieldLabel.Text = 'Second Term';
            app.SecondTerm = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Fibonacci_calculator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end